classdef TEC_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MarkededgesCheckBox             matlab.ui.control.CheckBox
        MarkedgesButton                 matlab.ui.control.StateButton
        OriginalROICheckBox             matlab.ui.control.CheckBox
        ShowanalysisregionButton        matlab.ui.control.StateButton
        SelectregionofinterestButton    matlab.ui.control.Button
        OpenimageButton                 matlab.ui.control.Button
        GreenchannelEditField           matlab.ui.control.NumericEditField
        GreenchannelEditFieldLabel      matlab.ui.control.Label
        GreenbrightbessSlider           matlab.ui.control.Slider
        GreenbrightbessSliderLabel      matlab.ui.control.Label
        RedbrightbessSlider             matlab.ui.control.Slider
        RedbrightbessSliderLabel        matlab.ui.control.Label
        NoisethresholdSlider            matlab.ui.control.Slider
        NoisethresholdSliderLabel       matlab.ui.control.Label
        EdgeswidthEditField             matlab.ui.control.NumericEditField
        EdgeswidthEditFieldLabel        matlab.ui.control.Label
        NumberofcellsintheregionEditFieldLabel  matlab.ui.control.Label
        NumberofcellsintheregionEditField  matlab.ui.control.NumericEditField
        DisplaystatsButton              matlab.ui.control.Button
        PixelspermicronEditFieldLabel   matlab.ui.control.Label
        PixelspermicronEditField        matlab.ui.control.NumericEditField
        SelectfullROIButton             matlab.ui.control.Button
        SaveprojectButton               matlab.ui.control.Button
        TECpercellEditFieldLabel        matlab.ui.control.Label
        TECpercellEditField             matlab.ui.control.EditField
        EdgegreenpermicronEditFieldLabel  matlab.ui.control.Label
        EdgegreenpermicronEditField     matlab.ui.control.EditField
        LoadprojectButton               matlab.ui.control.Button
        ClearedgesButton                matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        TotaledgeslengthmicronsEditFieldLabel  matlab.ui.control.Label
        TotaledgeslengthmicronsEditField  matlab.ui.control.EditField
        EdgeredpermicronEditFieldLabel  matlab.ui.control.Label
        EdgeredpermicronEditField       matlab.ui.control.EditField
        EdgeyellowpermicronEditFieldLabel  matlab.ui.control.Label
        EdgeyellowpermicronEditField    matlab.ui.control.EditField
        SumsamplesButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        fignum % main figure number
        filen % file name
        fullpath % full path to the image file
        RGBim % RGB image
        G_sat_val % Green saturation value (in percentage)
        R_sat_val % Red saturation value (in percentage)
        roiMask % Description
        analysisMask % mask of analysis region
        mask_brightness % between 0 to 1
        edgesMask % mask of colocalization edges
        edgesLength % array of edges length in pixels
        app_directory % application directory
        isBusy % indicates if the figure window is busy (with some analysis)
    end
    
    methods (Access = private)
        
        function refreshImage(app)
            Rs = app.R_sat_val;
            Gs = app.G_sat_val;
            figure(app.fignum);
            imshow(imadjust(app.RGBim, [0 0 0; Rs Gs 1]));
        end
        
        function bw = getAnalysisMask(app)
            Ro = 2; % open radius
            bw = app.roiMask;
            
            noise_thresh = 255*app.NoisethresholdSlider.Value/100;
            G = app.RGBim(:,:,2);
            G(G<noise_thresh) = 0;
            
            bw = bw.*(G~=0);
            bw = imclose(bw, strel('disk',Ro));
        end
        
        function bw = getEdgesMask(app)
            bw = app.edgesMask;
            Rd = app.EdgeswidthEditField.Value; % dilate radius
            bw = imdilate(bw, strel("disk", Rd));
        end
        
        function [TEC_per_cell, TEC_per_cell_per_area] = getTECintensity(app)
            % returns the average TEC signal
            % calculates the amount of red channel intensity in the
            % analysis region that is not on the edges
            
            R = app.RGBim(:,:,1);
            mask = app.analysisMask & ~getEdgesMask(app);
            R = R.*uint8(mask);
            tot_intensity = sum(R,"all");
            
            ppm = app.PixelspermicronEditField.Value;
            area = sum(mask,"all")/(ppm^2); % area in microns^2
            
            n = app.NumberofcellsintheregionEditField.Value;
            
            TEC_per_cell = tot_intensity/n;
            TEC_per_cell_per_area = TEC_per_cell/(n*area);
        end
        
        function [edge_red_per_micron, edge_green_per_micron, edge_yellow_per_micron, tot_L] = getEdgeSignal(app)
            
            R = app.RGBim(:,:,1);
            G = app.RGBim(:,:,2);
            mask = getEdgesMask(app);
            ppm = app.PixelspermicronEditField.Value;
            tot_L = sum(app.edgesLength)/ppm; % length in microns
            
            tot_G = sum(double(G).*double(mask)/255,"all");
            tot_R = sum(double(R).*double(mask)/255,"all");
            
            edge_red_per_micron = tot_R/tot_L;
            edge_green_per_micron = tot_G/tot_L;
            
            mix_sigma = 0.25*ppm;  % smear the boundaries
            Rg = imgaussfilt(R, mix_sigma);
            Gg = imgaussfilt(G, mix_sigma);
            tot_Y = sqrt(sum(double(Rg).*double(Gg).*double(mask)/255,"all"));
            
            edge_yellow_per_micron = tot_Y/tot_L;
            
        end
        
        function saveProject(app)
            
            wb = waitbar(0, 'Saving..');
            
            filen1 = app.filen;
            fullpath1 = app.fullpath;
            roiMask1 = app.roiMask;
            analysisMask1 = app.analysisMask;
            edgesMask1 = app.edgesMask;
            edgesLength1 = app.edgesLength;
            ncells = app.NumberofcellsintheregionEditField.Value;
            ppm = app.PixelspermicronEditField.Value;
            ew = app.EdgeswidthEditField.Value;
            
            
            savename = [app.app_directory, '\projects\', app.filen, '.mat'];
            save(savename,"filen1","fullpath1", "roiMask1","analysisMask1","edgesMask1","edgesLength1","ppm","ncells","ew");
            
            close(wb);
            
        end
        
        function loadProject(app, fullname)
            if nargin < 2
                [fname, p] = uigetfile('*.mat');
                if ~fname, return; end
                fullname = [p, fname];
            end
            load(fullname,"filen1","fullpath1", "roiMask1","analysisMask1","edgesMask1","edgesLength1","ppm","ncells","ew");
            
            if ~exist(fullpath1,"file")
                if ~isempty(strfind(fullpath1,"Q:\"))
                    fullpath1 = strrep(fullpath1, "Q:\", "Y:\");
                elseif ~isempty(strfind(fullpath1,"Y:\"))
                    fullpath1 = strrep(fullpath1, "Y:\", "Q:\");
                end
            end
            
            app.filen = filen1;
            app.fullpath = fullpath1;
            app.roiMask = roiMask1;
            app.analysisMask = analysisMask1;
            app.edgesMask = edgesMask1;
            app.edgesLength = edgesLength1;
            
            app.NumberofcellsintheregionEditField.Value = ncells;
            app.PixelspermicronEditField.Value = ppm;
            app.EdgeswidthEditField.Value = ew;
            
            data = bfopen(char(fullpath1));
            G = data{1}{app.GreenchannelEditField.Value};
            R = data{1}{(app.GreenchannelEditField.Value==1) + 1};
            dims = size(R);
            
            RGB = zeros([dims, 3],'uint8');
            RGB(:,:,1) = R;
            RGB(:,:,2) = G;
            app.RGBim = RGB;
            
            
            refreshImage(app);
            figure(app.UIFigure)
        end
        
        function setBusy(app, is_busy)
            app.isBusy = is_busy;
            col = [0, 0, 0];
            col(~is_busy + 1) = 1;
            app.Lamp.Color = col;
        end
        
        function L = getROIlength(app, roi)
            
            xy = roi.Position;
            L = 0;
            for i=2:length(xy)
                L = L + pdist(xy([i, i-1],:));
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('bfmatlab');
            [app.app_directory,~,~] = fileparts(mfilename('fullpath'));
            app.RGBim = zeros(1,1,3);
            app.fignum = 100;
            app.R_sat_val = 1;
            app.G_sat_val = 1;
            app.mask_brightness = 0.7;
            app.roiMask = [];
            app.analysisMask = [];
            app.edgesMask = [];
            app.isBusy = 0;
            app.edgesLength = [];
        end

        % Button pushed function: OpenimageButton
        function OpenimageButtonPushed(app, event)
            [FileName,PathName]=uigetfile({'*.*'});
            if ~FileName, return; end
            FullName=[PathName,FileName];
            app.fullpath = FullName;
            app.filen = FileName(1:end-4);
            
            data = bfopen(FullName);
            
            
            G = data{1}{app.GreenchannelEditField.Value};
            R = data{1}{(app.GreenchannelEditField.Value==1) + 1};
            dims = size(R);
            
            RGB = zeros([dims, 3],'uint8');
            RGB(:,:,1) = R;
            RGB(:,:,2) = G;
            app.RGBim = RGB;
            
            app.roiMask = ones(dims,'logical');
            app.analysisMask = getAnalysisMask(app);
            app.edgesMask = zeros(dims,'logical');
            
            refreshImage(app);
            figure(app.UIFigure)

        end

        % Value changed function: GreenbrightbessSlider
        function GreenbrightbessSliderValueChanged(app, event)
            value = app.GreenbrightbessSlider.Value;
            if ishandle(app.fignum) && ~app.isBusy
                app.G_sat_val = 1-value/100;
                refreshImage(app);
            end
        end

        % Value changed function: RedbrightbessSlider
        function RedbrightbessSliderValueChanged(app, event)
            value = app.RedbrightbessSlider.Value;
            if ishandle(app.fignum) && ~app.isBusy
                app.R_sat_val = 1-value/100;
                refreshImage(app);
            end
        end

        % Button pushed function: SelectregionofinterestButton
        function SelectregionofinterestButtonPushed(app, event)
            if ishandle(app.fignum) && ~app.isBusy
                setBusy(app,1);
                roi = drawfreehand;
                app.roiMask = createMask(roi);
                roi.delete;
                app.analysisMask = getAnalysisMask(app);
                setBusy(app,0);
            end
        end

        % Button pushed function: SelectfullROIButton
        function SelectfullROIButtonPushed(app, event)
            xy = size(app.RGBim,1:2);
            if xy(1)==0 || xy(2)==0, return; end
            app.roiMask = ones(xy, "logical");
            app.analysisMask = getAnalysisMask(app);
        end

        % Value changed function: ShowanalysisregionButton
        function ShowanalysisregionButtonValueChanged(app, event)
            value = app.ShowanalysisregionButton.Value;
            if app.OriginalROICheckBox.Value
                bw = app.roiMask;
            else
                bw = app.analysisMask;
            end
            if isempty(bw) && app.isBusy, return; end
            RGB = app.RGBim;
            if sum(size(bw) - size(RGB, [1:2]))==0
                if value
                    RGB(:,:,3) = uint8(app.mask_brightness*255*bw);
                    if app.MarkededgesCheckBox.Value
                        edge_bw = getEdgesMask(app);
                        RGB(:,:,3) = uint8(app.mask_brightness*255*edge_bw);
                    end
                else
                    RGB(:,:,3) = 0;
                end
                
                app.RGBim = RGB;
                refreshImage(app);
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            bw = app.edgesMask;
            bw = imdilate(bw, strel("disk", 5));
            imshow(bw)

        end

        % Value changed function: MarkedgesButton
        function MarkedgesButtonValueChanged(app, event)
            
            if ishandle(app.fignum) && ~app.isBusy
                setBusy(app,1);
                
                ROIs = [];
                stop_flag = 0;
                while ~stop_flag
                    roi = drawfreehand("Closed",false);
                    
                    if size(roi.Position, 1) < 4 % some minimal thresh length
                        stop_flag = 1;
                        roi.delete;
                    else
                        ROIs = [ROIs; roi];
                    end
                end
                
                
                edge_bw = app.edgesMask;
                edge_L = app.edgesLength;
                bw = zeros(size(edge_bw),"logical");
                for i=1:length(ROIs)
                    roi = ROIs(i);
                    r_mask = createMask(roi);
                    bw = bw | r_mask;
                    L = getROIlength(app, roi);
                    edge_L = [edge_L; L];
                    roi.delete;
                end
                edge_bw = edge_bw | bw;
                app.edgesMask = edge_bw;
                app.edgesLength = edge_L;
                
                app.MarkedgesButton.Value = 0;
                
                setBusy(app,0);
            end
        end

        % Button pushed function: DisplaystatsButton
        function DisplaystatsButtonPushed(app, event)
            [TEC_per_cell, ~] = getTECintensity(app);
            app.TECpercellEditField.Value = num2str(TEC_per_cell);
            
            [edge_red_per_micron, edge_green_per_micron, edge_yellow_per_micron, tot_L] = getEdgeSignal(app);
            app.EdgeredpermicronEditField.Value = num2str(edge_red_per_micron);
            app.EdgegreenpermicronEditField.Value = num2str(edge_green_per_micron);
            app.EdgeyellowpermicronEditField.Value = num2str(edge_yellow_per_micron);
            app.TotaledgeslengthmicronsEditField.Value = num2str(tot_L);
            
            clipboard("copy", [num2str(TEC_per_cell), ' ', num2str(edge_red_per_micron), ' ', num2str(edge_green_per_micron), ' ', num2str(edge_yellow_per_micron), ' ', num2str(tot_L)]);
        end

        % Button pushed function: SaveprojectButton
        function SaveprojectButtonPushed(app, event)
            saveProject(app);
        end

        % Button pushed function: LoadprojectButton
        function LoadprojectButtonPushed(app, event)
            loadProject(app);
        end

        % Value changed function: ClearedgesButton
        function ClearedgesButtonValueChanged(app, event)
            value = app.ClearedgesButton.Value;
            if ~isempty(app.edgesMask)
                app.edgesMask(:,:) = 0; 
                app.edgesLength = [];
            end
            app.ClearedgesButton.Value = 0;
        end

        % Button pushed function: SumsamplesButton
        function SumsamplesButtonPushed(app, event)
            [filenames, filespath] = uigetfile('*.mat', 'MultiSelect',"on");
            if ~isa(filenames, "cell") 
                fn = filenames;
                filenames = cell(1,1); 
                filenames{1} = fn;
            end
            if filenames{1} == 0, return; end
            ew = app.EdgeswidthEditField.Value;
            tbl = [" "; "TEC per cell:";"Edge green per micron:";"Edge red per micron:";"Edge yellow per micron:";"Total edges length (microns):"];
            for i=1:length(filenames)
                fullp = [filespath, filenames{i}];
                loadProject(app, fullp);
                app.EdgeswidthEditField.Value = ew;
                tbl(1,i+1) = filenames{i};
                
                [TEC_per_cell, ~] = getTECintensity(app);
                tbl(2,i+1) = num2str(TEC_per_cell);
                
                [edge_red_per_micron, edge_green_per_micron, edge_yellow_per_micron, tot_L] = getEdgeSignal(app);
                tbl(3,i+1) = num2str(edge_red_per_micron);
                tbl(4,i+1) = num2str(edge_green_per_micron);
                tbl(5,i+1) = num2str(edge_yellow_per_micron);
                tbl(6,i+1) = num2str(tot_L);
                
                writematrix(tbl, ['Samples_Summary_ew=', num2str(app.EdgeswidthEditField.Value),'.xls']);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MarkededgesCheckBox = uicheckbox(app.UIFigure);
            app.MarkededgesCheckBox.Text = 'Marked edges';
            app.MarkedgesButton = uibutton(app.UIFigure, 'state');
            app.MarkedgesButton.ValueChangedFcn = createCallbackFcn(app, @MarkedgesButtonValueChanged, true);
            app.MarkedgesButton.Text = 'Mark edges';
            app.OriginalROICheckBox = uicheckbox(app.UIFigure);
            app.OriginalROICheckBox.Text = 'Original ROI';
            app.ShowanalysisregionButton = uibutton(app.UIFigure, 'state');
            app.ShowanalysisregionButton.ValueChangedFcn = createCallbackFcn(app, @ShowanalysisregionButtonValueChanged, true);
            app.ShowanalysisregionButton.Text = 'Show analysis region';
            app.SelectregionofinterestButton = uibutton(app.UIFigure, 'push');
            app.SelectregionofinterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectregionofinterestButtonPushed, true);
            app.SelectregionofinterestButton.Text = 'Select region of interest';
            app.OpenimageButton = uibutton(app.UIFigure, 'push');
            app.OpenimageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimageButtonPushed, true);
            app.OpenimageButton.Text = 'Open image';
            app.GreenchannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenchannelEditField.Limits = [1 2];
            app.GreenchannelEditField.RoundFractionalValues = 'on';
            app.GreenchannelEditField.Value = 1;
            app.GreenchannelEditFieldLabel = uilabel(app.UIFigure);
            app.GreenchannelEditFieldLabel.Text = 'Green channel';
            app.GreenbrightbessSlider = uislider(app.UIFigure);
            app.GreenbrightbessSlider.Limits = [0 99];
            app.GreenbrightbessSlider.MajorTicks = [];
            app.GreenbrightbessSlider.ValueChangedFcn = createCallbackFcn(app, @GreenbrightbessSliderValueChanged, true);
            app.GreenbrightbessSlider.MinorTicks = [];
            app.GreenbrightbessSliderLabel = uilabel(app.UIFigure);
            app.GreenbrightbessSliderLabel.Text = 'Green brightbess';
            app.RedbrightbessSlider = uislider(app.UIFigure);
            app.RedbrightbessSlider.Limits = [0 99];
            app.RedbrightbessSlider.MajorTicks = [];
            app.RedbrightbessSlider.ValueChangedFcn = createCallbackFcn(app, @RedbrightbessSliderValueChanged, true);
            app.RedbrightbessSlider.MinorTicks = [];
            app.RedbrightbessSliderLabel = uilabel(app.UIFigure);
            app.RedbrightbessSliderLabel.Text = 'Red brightbess';
            app.NoisethresholdSlider = uislider(app.UIFigure);
            app.NoisethresholdSlider.Limits = [0 20];
            app.NoisethresholdSlider.MajorTicks = [];
            app.NoisethresholdSlider.MinorTicks = [];
            app.NoisethresholdSliderLabel = uilabel(app.UIFigure);
            app.NoisethresholdSliderLabel.Text = 'Noise threshold';
            app.EdgeswidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeswidthEditField.Limits = [0 Inf];
            app.EdgeswidthEditField.RoundFractionalValues = 'on';
            app.EdgeswidthEditField.Value = 10;
            app.EdgeswidthEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeswidthEditFieldLabel.Text = 'Edges width';
            app.NumberofcellsintheregionEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcellsintheregionEditFieldLabel.Text = 'Number of cells in the region';
            app.NumberofcellsintheregionEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcellsintheregionEditField.Limits = [0 Inf];
            app.NumberofcellsintheregionEditField.RoundFractionalValues = 'on';
            app.NumberofcellsintheregionEditField.Value = 10;
            app.DisplaystatsButton = uibutton(app.UIFigure, 'push');
            app.DisplaystatsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystatsButtonPushed, true);
            app.DisplaystatsButton.Text = 'Display stats';
            app.PixelspermicronEditFieldLabel = uilabel(app.UIFigure);
            app.PixelspermicronEditFieldLabel.Text = 'Pixels per micron';
            app.PixelspermicronEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelspermicronEditField.Limits = [1e-05 Inf];
            app.PixelspermicronEditField.Value = 19.16;
            app.SelectfullROIButton = uibutton(app.UIFigure, 'push');
            app.SelectfullROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfullROIButtonPushed, true);
            app.SelectfullROIButton.Text = 'Select full ROI';
            app.SaveprojectButton = uibutton(app.UIFigure, 'push');
            app.SaveprojectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprojectButtonPushed, true);
            app.SaveprojectButton.Text = 'Save project';
            app.TECpercellEditFieldLabel = uilabel(app.UIFigure);
            app.TECpercellEditFieldLabel.Text = 'TEC per cell:';
            app.TECpercellEditField = uieditfield(app.UIFigure, 'text');
            app.TECpercellEditField.Editable = 'off';
            app.TECpercellEditField.Value = '0';
            app.EdgegreenpermicronEditFieldLabel = uilabel(app.UIFigure);
            app.EdgegreenpermicronEditFieldLabel.Text = 'Edge green per micron:';
            app.EdgegreenpermicronEditField = uieditfield(app.UIFigure, 'text');
            app.EdgegreenpermicronEditField.Editable = 'off';
            app.EdgegreenpermicronEditField.Value = '0';
            app.LoadprojectButton = uibutton(app.UIFigure, 'push');
            app.LoadprojectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprojectButtonPushed, true);
            app.LoadprojectButton.Text = 'Load project';
            app.ClearedgesButton = uibutton(app.UIFigure, 'state');
            app.ClearedgesButton.ValueChangedFcn = createCallbackFcn(app, @ClearedgesButtonValueChanged, true);
            app.ClearedgesButton.Text = 'Clear edges';
            app.Lamp = uilamp(app.UIFigure);
            app.TotaledgeslengthmicronsEditFieldLabel = uilabel(app.UIFigure);
            app.TotaledgeslengthmicronsEditFieldLabel.Text = 'Total edges length (microns):';
            app.TotaledgeslengthmicronsEditField = uieditfield(app.UIFigure, 'text');
            app.TotaledgeslengthmicronsEditField.Editable = 'off';
            app.TotaledgeslengthmicronsEditField.Value = '0';
            app.EdgeredpermicronEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeredpermicronEditFieldLabel.Text = 'Edge red per micron:';
            app.EdgeredpermicronEditField = uieditfield(app.UIFigure, 'text');
            app.EdgeredpermicronEditField.Editable = 'off';
            app.EdgeredpermicronEditField.Value = '0';
            app.EdgeyellowpermicronEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeyellowpermicronEditFieldLabel.Text = 'Edge yellow per micron:';
            app.EdgeyellowpermicronEditField = uieditfield(app.UIFigure, 'text');
            app.EdgeyellowpermicronEditField.Editable = 'off';
            app.EdgeyellowpermicronEditField.Value = '0';
            app.SumsamplesButton = uibutton(app.UIFigure, 'push');
            app.SumsamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SumsamplesButtonPushed, true);
            app.SumsamplesButton.Text = 'Sum samples';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEC_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end